classdef AnisEulerSC_Inputs < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        SetupTab                        matlab.ui.container.Tab
        InitialvalueforSCschemeDropDownLabel  matlab.ui.control.Label
        InitialvalueforSCschemeDropDown  matlab.ui.control.DropDown
        SCschemeDropDown_2Label         matlab.ui.control.Label
        SCschemeDropDown                matlab.ui.control.DropDown
        FormatofEulerfileDropDownLabel  matlab.ui.control.Label
        FormatofEulerfileDropDown       matlab.ui.control.DropDown
        OriginofEulerfileDropDownLabel  matlab.ui.control.Label
        OriginofEulerfileDropDown       matlab.ui.control.DropDown
        Xsofazimuthinrange0to360degreesEditFieldLabel  matlab.ui.control.Label
        XsAzEditField                   matlab.ui.control.NumericEditField
        AnisEulerSCpathEditFieldLabel   matlab.ui.control.Label
        AnisEulerSCpathEditField        matlab.ui.control.EditField
        TitleEditFieldLabel             matlab.ui.control.Label
        TitleEditField                  matlab.ui.control.EditField
        MaximumnumberofSCiterationseg40EditFieldLabel  matlab.ui.control.Label
        MaxSCiterationsEditField        matlab.ui.control.NumericEditField
        MaximumerrorforGreenstensoreg01EditFieldLabel  matlab.ui.control.Label
        MaxGreenstensorEditField        matlab.ui.control.NumericEditField
        MaximumerrorforSCconvergenceeg01EditFieldLabel  matlab.ui.control.Label
        MaxSCconvergenceEditField       matlab.ui.control.NumericEditField
        SaveSetupButton                 matlab.ui.control.Button
        ClearSetupButton                matlab.ui.control.Button
        LoadSetupButton                 matlab.ui.control.Button
        NoteInputparametersarestoredinafilenamedTitle_ModelSCmatLabel  matlab.ui.control.Label
        NoteInputparametersarestoredinafilenamedTitle_ModelSCmatLabel_2  matlab.ui.control.Label
        Inputs_ModelSCmatLabel          matlab.ui.control.Label
        SinglecrystalpropertiesTab      matlab.ui.container.Tab
        CurrentstateTextAreaLabel       matlab.ui.control.Label
        CurrentstateSingleCrystalElasticityTextArea  matlab.ui.control.TextArea
        PhasePanel                      matlab.ui.container.Panel
        SinglecrystalelasticstiffnesstensorinVoigtmatrixCijGPaLabel  matlab.ui.control.Label
        CrystalsymmetryEditFieldLabel   matlab.ui.control.Label
        crystalSymmetryEditField        matlab.ui.control.EditField
        CrystalreferenceframeEditFieldLabel  matlab.ui.control.Label
        crystalReferenceFrameEditField  matlab.ui.control.EditField
        ReferenceEditFieldLabel         matlab.ui.control.Label
        ReferenceEditField              matlab.ui.control.EditField
        SaveSingleCrystalElasticityButton  matlab.ui.control.Button
        NameEditFieldLabel              matlab.ui.control.Label
        NameEditField                   matlab.ui.control.EditField
        ClearPhaseButton                matlab.ui.control.Button
        C11EditField                    matlab.ui.control.EditField
        C12EditField                    matlab.ui.control.EditField
        C13EditField                    matlab.ui.control.EditField
        C14EditField                    matlab.ui.control.EditField
        C15EditField                    matlab.ui.control.EditField
        C22EditField                    matlab.ui.control.EditField
        C23EditField                    matlab.ui.control.EditField
        C24EditField                    matlab.ui.control.EditField
        C25EditField                    matlab.ui.control.EditField
        C16EditField                    matlab.ui.control.EditField
        C26EditField                    matlab.ui.control.EditField
        C33EditField                    matlab.ui.control.EditField
        C34EditField                    matlab.ui.control.EditField
        C35EditField                    matlab.ui.control.EditField
        C36EditField                    matlab.ui.control.EditField
        C44EditField                    matlab.ui.control.EditField
        C45EditField                    matlab.ui.control.EditField
        C46EditField                    matlab.ui.control.EditField
        C55EditField                    matlab.ui.control.EditField
        C56EditField                    matlab.ui.control.EditField
        C66EditField                    matlab.ui.control.EditField
        Densitygcm3EditFieldLabel       matlab.ui.control.Label
        Densitygcm3EditField            matlab.ui.control.EditField
        crystalCoordinateSystemLabel    matlab.ui.control.Label
        aEditFieldLabel                 matlab.ui.control.Label
        aEditField                      matlab.ui.control.EditField
        bEditFieldLabel                 matlab.ui.control.Label
        bEditField                      matlab.ui.control.EditField
        alphaEditFieldLabel             matlab.ui.control.Label
        alphaEditField                  matlab.ui.control.EditField
        betaEditFieldLabel              matlab.ui.control.Label
        betaEditField                   matlab.ui.control.EditField
        cEditFieldLabel                 matlab.ui.control.Label
        cEditField                      matlab.ui.control.EditField
        gammaEditFieldLabel             matlab.ui.control.Label
        gammaEditField                  matlab.ui.control.EditField
        VolumefractionEditFieldLabel    matlab.ui.control.Label
        VolumefractionEditField         matlab.ui.control.EditField
        NoEditFieldLabel                matlab.ui.control.Label
        NoEditField                     matlab.ui.control.EditField
        LoadSingleCrystalPropButton     matlab.ui.control.Button
        NameEditField_2Label            matlab.ui.control.Label
        LoadSingleCrystalPropNameEditField  matlab.ui.control.EditField
        CheckPhasesButton               matlab.ui.control.Button
        ClearCheckPhasesButton          matlab.ui.control.Button
        HowmanyphasesEditFieldLabel     matlab.ui.control.Label
        HowmanyphasesEditField          matlab.ui.control.NumericEditField
        GrainshapesandorientationsTab   matlab.ui.container.Tab
        CurrentstateTextArea_2Label     matlab.ui.control.Label
        CurrentstateGrainShapeOrientationTextArea  matlab.ui.control.TextArea
        CheckGrainButton                matlab.ui.control.Button
        ClearCheckGrainButton           matlab.ui.control.Button
        PhasePanel_2                    matlab.ui.container.Panel
        EulerspaceforcrystalegtriclinicsamplesymmetryLabel  matlab.ui.control.Label
        GrainshapeDropDownLabel         matlab.ui.control.Label
        GrainShapeDropDown              matlab.ui.control.DropDown
        X1DropDownLabel                 matlab.ui.control.Label
        X1DropDown                      matlab.ui.control.DropDown
        X3DropDownLabel                 matlab.ui.control.Label
        X3DropDown                      matlab.ui.control.DropDown
        NameLabel                       matlab.ui.control.Label
        NameGrainShapeOrientationEditField  matlab.ui.control.EditField
        EulerdatafileEditFieldLabel     matlab.ui.control.Label
        EulerdatafileEditField          matlab.ui.control.EditField
        AZINCLabel                      matlab.ui.control.Label
        OrientationofellipsoidsemiaxesDropDownLabel  matlab.ui.control.Label
        OrientationEllipsoidDropDown    matlab.ui.control.DropDown
        GrainshapedefinitionLabel       matlab.ui.control.Label
        GrainshapedefinitionLabel_2     matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        CrystalsymmetryDropDownLabel    matlab.ui.control.Label
        CrystalsymmetryDropDown         matlab.ui.control.DropDown
        FoliationwithpolegivenbyAZ0360andINC090Label  matlab.ui.control.Label
        AZLabel                         matlab.ui.control.Label
        INCLabel                        matlab.ui.control.Label
        A1EditFieldLabel                matlab.ui.control.Label
        A1AZEditField                   matlab.ui.control.EditField
        A1INCEditField                  matlab.ui.control.EditField
        A3INCEditField                  matlab.ui.control.EditField
        A3EditFieldLabel                matlab.ui.control.Label
        A3AZEditField                   matlab.ui.control.EditField
        SaveGrainShapeOrientationButton  matlab.ui.control.Button
        ClearGrainShapeOrientationButton  matlab.ui.control.Button
        ProperpointgroupsSchoenfliesInternationalphi1PHIphi2Label  matlab.ui.control.Label
        X1ind1EditField                 matlab.ui.control.EditField
        X1ind2EditField                 matlab.ui.control.EditField
        X1ind3EditField                 matlab.ui.control.EditField
        X3ind1EditField                 matlab.ui.control.EditField
        X3ind2EditField                 matlab.ui.control.EditField
        X3ind3EditField                 matlab.ui.control.EditField
        EllipsoidsemiaxeslengthsA1A2A3Label  matlab.ui.control.Label
        EllipsoidA1EditField            matlab.ui.control.EditField
        EllipsoidA2EditField            matlab.ui.control.EditField
        EllipsoidA3EditField            matlab.ui.control.EditField
        Note0NocrystalsymmetryoperationsegfluidsLabel  matlab.ui.control.Label
        LoadGrainShapeOrientationButton  matlab.ui.control.Button
        NameEditField_3Label            matlab.ui.control.Label
        LoadGrainShapeOrientationNameEditField  matlab.ui.control.EditField
        SummaryTab                      matlab.ui.container.Tab
        SummaryTextArea                 matlab.ui.control.TextArea
        ShowButton                      matlab.ui.control.Button
        HelpTab                         matlab.ui.container.Tab
        TabGroup2                       matlab.ui.container.TabGroup
        AboutAnisEulerSCInputsTab       matlab.ui.container.Tab
        AnisEulerSCInputsisdesignedtoLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        modelSC % Struct to save input and output parameters in app
        %ModelSC % Struct to save input and output parameters in MAT file
        
        % Tab: Setup
        dir_Input % Input files directory
        dir_Output % Output files directory
        Title % Title of modeling (to be used for naming output files)
        filename % File name of MAT file including SC input parameters
        ioptsc % Index of SC scheme
        isch % Index of initial value for SC scheme
        ioptn % Index of Euler file
        ieuler % Index of origin of Euler angles
        XsAz % Azimuth of Xs (in range 0 to 360 degrees)
        nsc % Maximum number SC iterations (e.g., 40)
        errmax % Maximum error(%) for Green's Tensor (e.g., 0.1)
        conmax % Maximum error(%) for SC convergence (e.g., 0.1)
        
        
        % Tab: SC aggregate
        %   Tab: Single crystal elasticity 
        nmin % Total number of phases
        mineral % List of minerals
        vf % Volume fraction
        reference % Reference      
        rho % Density 
        Csymmetry % Crystal symmetry
        CrefFrame % Crystal reference frame
        abc % (a,b,c) in crystal coordinate system
        abg % (alpha,beta,gamma) in crystal coordinate system
        index1 = 1; % index
            
        M % Single crystal elastic stiffness tensor (Voigt Matrix)
        cs % Defined crystal symmetry
        
        Ofile % Euler data file
        
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UIFigure.Name = 'AnisEulerSC-Inputs';
            app.modelSC = [];
            app.Densitygcm3EditFieldLabel.Text = ['Density (g/cm',char(179),')'];
            app.Densitygcm3EditFieldLabel.Position = [9,420,89,22];
            
            answer = strcmp(app.GrainShapeDropDown.Value,'1 = Sphere');
            if answer == 1
                app.OrientationEllipsoidDropDown.Enable = 'off';
                app.EllipsoidA1EditField.Enable = 'off';
                app.EllipsoidA2EditField.Enable = 'off';
                app.EllipsoidA3EditField.Enable = 'off';
                app.A1AZEditField.Enable = 'off';
                app.A1INCEditField.Enable = 'off';
                app.A3INCEditField.Enable = 'off';
                app.A3AZEditField.Enable = 'off';                
            end
            
            answer = strcmp(app.OrientationEllipsoidDropDown.Value,'1 = // tensor axes X1, X2, X3 of each crystal (A1//X1, A2//X2, A3//X3)');
            if answer == 1
                app.A1AZEditField.Enable = 'off';
                app.A1INCEditField.Enable = 'off';
                app.A3INCEditField.Enable = 'off';
                app.A3AZEditField.Enable = 'off';
            end
        end

        % Button pushed function: SaveSingleCrystalElasticityButton
        function SaveSingleCrystalElasticityButtonPushed(app, event)
            % Evaluate errors                                         
            if isempty(app.NoEditField.Value)
                uialert(app.UIFigure,'Error: No. of phase is not specified.','Error');
            elseif str2double(app.NoEditField.Value) > app.HowmanyphasesEditField.Value
                uialert(app.UIFigure,'Error: No. of phase exceed total number of phases.','Error');
            end
            
            if isempty(app.NameEditField.Value)
                uialert(app.UIFigure,'Error: Name of phase is not specified.','Error');
            end
            
            if isempty(app.VolumefractionEditField.Value)
                uialert(app.UIFigure,'Error: Volume fraction of phase is not specified.','Error');
            end
            
            if isempty(app.ReferenceEditField.Value)
                uialert(app.UIFigure,'Error: Reference of phase is not specified.','Error');               
            end
            
            if isempty(app.Densitygcm3EditField.Value)
                uialert(app.UIFigure,'Error: Density of phase is not specified.','Error');
            end
            
            if isempty(app.crystalSymmetryEditField.Value)
                uialert(app.UIFigure,'Error: Crystal symmetry of phase is not specified.','Error');
            end
            
            if isempty(app.crystalReferenceFrameEditField.Value)
                uialert(app.UIFigure,'Error: Crystal reference frame of phase is not specified.','Error');
            end
            
            if isempty(app.aEditField.Value) || isempty(app.bEditField.Value) || isempty(app.cEditField.Value)
                uialert(app.UIFigure,'Error: Crystal coordinate system (a,b,c) of phase is not specified.','Error');
            end
            
            if isempty(app.alphaEditField.Value) || isempty(app.betaEditField.Value) || isempty(app.gammaEditField.Value)
                uialert(app.UIFigure,'Error: Crystal coordinate system (alpha,beta,gamma) of phase is not specified.','Error');
            end        
            
            % Save Cartesian Tensor crystal symmetry & frame
            app.nmin = app.HowmanyphasesEditField.Value; % Total number of phases
            minn = str2double(app.NoEditField.Value);
            minName = app.NameEditField.Value;
            
            app.modelSC.dir_Input  = deblank(app.AnisEulerSCpathEditField.Value);
            app.modelSC.dir_Output = deblank(app.AnisEulerSCpathEditField.Value);
            app.modelSC.Title = deblank(app.TitleEditField.Value); 
            app.modelSC.Phase.(minName).No = minn;
            app.modelSC.Phase.(minName).vf = str2double(app.VolumefractionEditField.Value);
            app.modelSC.Phase.(minName).reference = {app.ReferenceEditField.Value};
            app.modelSC.Phase.(minName).rho = str2double(app.Densitygcm3EditField.Value);
            app.modelSC.Phase.(minName).crystalSymmetry = {app.crystalSymmetryEditField.Value};
            app.modelSC.Phase.(minName).crystalReferenceFrame = {app.crystalReferenceFrameEditField.Value};
            app.modelSC.Phase.(minName).abc = [str2double(app.aEditField.Value),str2double(app.bEditField.Value),str2double(app.cEditField.Value)];
            app.modelSC.Phase.(minName).abg = [str2double(app.alphaEditField.Value),str2double(app.betaEditField.Value),str2double(app.gammaEditField.Value)];
            
            app.modelSC.Phase.(minName).index1 = app.index1;
            app.modelSC.Phase.(minName).M = zeros(6,6);               
            app.modelSC.Phase.(minName).M(1,1) = str2double(app.C11EditField.Value);
            app.modelSC.Phase.(minName).M(1,2) = str2double(app.C12EditField.Value);
            app.modelSC.Phase.(minName).M(1,3) = str2double(app.C13EditField.Value);
            app.modelSC.Phase.(minName).M(1,4) = str2double(app.C14EditField.Value);
            app.modelSC.Phase.(minName).M(1,5) = str2double(app.C15EditField.Value);    
            app.modelSC.Phase.(minName).M(1,6) = str2double(app.C16EditField.Value);
            app.modelSC.Phase.(minName).M(2,2) = str2double(app.C22EditField.Value);
            app.modelSC.Phase.(minName).M(2,3) = str2double(app.C23EditField.Value);
            app.modelSC.Phase.(minName).M(2,4) = str2double(app.C24EditField.Value);
            app.modelSC.Phase.(minName).M(2,5) = str2double(app.C25EditField.Value);
            app.modelSC.Phase.(minName).M(2,6) = str2double(app.C26EditField.Value);
            app.modelSC.Phase.(minName).M(3,3) = str2double(app.C33EditField.Value);
            app.modelSC.Phase.(minName).M(3,4) = str2double(app.C34EditField.Value);
            app.modelSC.Phase.(minName).M(3,5) = str2double(app.C35EditField.Value);
            app.modelSC.Phase.(minName).M(3,6) = str2double(app.C36EditField.Value);
            app.modelSC.Phase.(minName).M(4,4) = str2double(app.C44EditField.Value);
            app.modelSC.Phase.(minName).M(4,5) = str2double(app.C45EditField.Value);
            app.modelSC.Phase.(minName).M(4,6) = str2double(app.C46EditField.Value);
            app.modelSC.Phase.(minName).M(5,5) = str2double(app.C55EditField.Value);
            app.modelSC.Phase.(minName).M(5,6) = str2double(app.C56EditField.Value);
            app.modelSC.Phase.(minName).M(6,6) = str2double(app.C66EditField.Value);    
            
            if isempty(find(app.modelSC.Phase.(minName).M,1))
                uialert(app.UIFigure,'Error: Single crystal elastic constants (Cij) of phase is not specified.','Error');
            else
                % Matrix is symmetric: Mij = Mji
                for i = 1:6
                    for j = i+1:6
                        app.modelSC.Phase.(minName).M(j,i) = app.modelSC.Phase.(minName).M(i,j);
                    end                    
                end
            end
            
            app.modelSC.nPhase = length(fieldnames(app.modelSC.Phase)); 
            if app.modelSC.nPhase == app.nmin
                uialert(app.UIFigure,'All phases are specified and saved!','Success','Icon','Success');
            elseif app.modelSC.nPhase < app.nmin
                uialert(app.UIFigure,'Number of current phases is smaller than total number!','Warning','Icon','Warning');
            elseif app.modelSC.nPhase > app.nmin
                uialert(app.UIFigure,'Number of current phases is larger than total number!','Warning','Icon','Warning');
            end
                
            app.modelSC.PhaseList = fieldnames(app.modelSC.Phase);
            
            ModelSC = app.modelSC;
            
            app.filename = [deblank(app.AnisEulerSCpathEditField.Value),deblank(app.TitleEditField.Value),'_Inputs_ModelSC.mat'];
            save(app.filename,'ModelSC');


        end

        % Button pushed function: ClearPhaseButton
        function ClearPhaseButtonPushed(app, event)
            
            app.NoEditField.Value = '';
            app.NameEditField.Value = '';
            app.VolumefractionEditField.Value = '';
            app.ReferenceEditField.Value = '';
            app.Densitygcm3EditField.Value = '';
            app.crystalSymmetryEditField.Value = '';
            app.crystalReferenceFrameEditField.Value = '';
            app.aEditField.Value = '';
            app.bEditField.Value = '';
            app.cEditField.Value = '';
            app.alphaEditField.Value = '';
            app.betaEditField.Value = '';
            app.gammaEditField.Value = '';
            app.C11EditField.Value = '';
            app.C12EditField.Value = '';
            app.C13EditField.Value = '';
            app.C14EditField.Value = '';
            app.C15EditField.Value = '';
            app.C16EditField.Value = '';
            app.C22EditField.Value = '';
            app.C23EditField.Value = '';
            app.C24EditField.Value = '';
            app.C25EditField.Value = '';
            app.C26EditField.Value = '';
            app.C33EditField.Value = '';
            app.C34EditField.Value = '';
            app.C35EditField.Value = '';
            app.C36EditField.Value = '';
            app.C44EditField.Value = '';
            app.C45EditField.Value = '';
            app.C46EditField.Value = '';
            app.C55EditField.Value = '';
            app.C56EditField.Value = '';
            app.C66EditField.Value = '';
            app.LoadSingleCrystalPropNameEditField.Value = '';
        
        end

        % Button pushed function: SaveSetupButton
        function SaveSetupButtonPushed(app, event)
            
            app.modelSC.dir_Input  = deblank(app.AnisEulerSCpathEditField.Value);
            app.modelSC.dir_Output = deblank(app.AnisEulerSCpathEditField.Value);
            app.modelSC.Title      = deblank(app.TitleEditField.Value);            
            
            %app.filename = [deblank(app.AnisEulerSCpathEditField.Value),deblank(app.TitleEditField.Value),'_Inputs_ModelSC.mat'];
            
            str_SCscheme = split(app.SCschemeDropDown.Value);
            if isnan(str2double(str_SCscheme(1)))
                uialert(app.UIFigure,'Error: SC scheme is not selected.','Error');
            else
                app.modelSC.ioptsc = str2double(str_SCscheme(1));
            end
            
            str_InitSCscheme = split(app.InitialvalueforSCschemeDropDown.Value);
            if isnan(str2double(str_InitSCscheme(1)))
                uialert(app.UIFigure,'Error: Initial value for SC scheme is not selected.','Error');
            else
                app.modelSC.isch = str2double(str_InitSCscheme(1));
            end
            
            str_FormatofEulerfile = split(app.FormatofEulerfileDropDown.Value);
            if isnan(str2double(str_FormatofEulerfile(1)))
                uialert(app.UIFigure,'Error: Format of Euler file is not selected.','Error');
            else
                app.modelSC.ioptn = str2double(str_FormatofEulerfile(1));
            end
            
            str_OriginofEulerfile = split(app.OriginofEulerfileDropDown.Value);
            if isnan(str2double(str_OriginofEulerfile(1)))
                uialert(app.UIFigure,'Error: Origin of Euler file is not selected.','Error');
            else
                app.modelSC.ieuler = str2double(str_OriginofEulerfile(1));
            end
            
            app.modelSC.XsAz = app.XsAzEditField.Value;
            
            if app.MaxSCiterationsEditField.Value == 0
                uialert(app.UIFigure,'Error: Maximum number of SC iterations is not specified.','Error');
            else
                app.modelSC.nsc = app.MaxSCiterationsEditField.Value;
            end
            
            if app.MaxGreenstensorEditField.Value == 0
                uialert(app.UIFigure,'Error: Maximum error(%%) for Green''s tensor is not specified.','Error');
            else
                app.modelSC.errmax = app.MaxGreenstensorEditField.Value;
            end
            
            if app.MaxSCconvergenceEditField.Value == 0
                uialert(app.UIFigure,'Error: Maximum error(%%) for SC convergence is not specified.','Error');
            else
                app.modelSC.conmax  = app.MaxSCconvergenceEditField.Value;   
            end


%             app.modelSC.Phase = [];
%             app.modelSC.nPhase = 0;
%             app.modelSC.PhaseList = {};

            ModelSC = app.modelSC;
            app.filename = [deblank(app.AnisEulerSCpathEditField.Value),deblank(app.TitleEditField.Value),'_Inputs_ModelSC.mat'];
            save(app.filename,'ModelSC');


        end

        % Button pushed function: ClearSetupButton
        function ClearSetupButtonPushed(app, event)
            app.AnisEulerSCpathEditField.Value = '';
            app.TitleEditField.Value = '';
            app.SCschemeDropDown.Value = app.SCschemeDropDown.Items(1);
            app.InitialvalueforSCschemeDropDown.Value = app.InitialvalueforSCschemeDropDown.Items(1);
            app.FormatofEulerfileDropDown.Value = app.FormatofEulerfileDropDown.Items(1);
            app.OriginofEulerfileDropDown.Value = app.OriginofEulerfileDropDown.Items(1);
            app.XsAzEditField.Value = 0;
            app.MaxSCiterationsEditField.Value = 0;
            app.MaxGreenstensorEditField.Value = 0;
            app.MaxSCconvergenceEditField.Value = 0;
            
        end

        % Button pushed function: CheckPhasesButton
        function CheckPhasesButtonPushed(app, event)
            numPhase = length(fieldnames(app.modelSC.Phase));
            cellArrayText = cell(1,7*numPhase);
            for i = 1:numPhase
                minName = cell2mat(app.modelSC.PhaseList(i,1));                        
                minNo = app.modelSC.Phase.(minName).No;
                minvf = app.modelSC.Phase.(minName).vf;
                minref = cell2mat(app.modelSC.Phase.(minName).reference);
                minrho = app.modelSC.Phase.(minName).rho;
                minCS = cell2mat(app.modelSC.Phase.(minName).crystalSymmetry);
                minCRF = cell2mat(app.modelSC.Phase.(minName).crystalReferenceFrame);
                minabc = [app.modelSC.Phase.(minName).abc(1),app.modelSC.Phase.(minName).abc(2),app.modelSC.Phase.(minName).abc(3)];
                minabg = [app.modelSC.Phase.(minName).abg(1),app.modelSC.Phase.(minName).abg(2),app.modelSC.Phase.(minName).abg(3)];
                
                cellArrayText{1+7*(i-1)} = sprintf('No. %g   %s   \n\nVolume fraction: %g\n\n',minNo,minName,minvf);
                cellArrayText{2+7*(i-1)} = sprintf('Reference: %s \n\n',minref);
                cellArrayText{3+7*(i-1)} = sprintf(['Density: %6.4f (g/cm',char(179),')\n\n'],minrho);
                cellArrayText{4+7*(i-1)} = sprintf('Crystal symmetry: %s\n\n',minCS);
                cellArrayText{5+7*(i-1)} = sprintf('Crystal reference frame: %s\n\n',minCRF);
                cellArrayText{6+7*(i-1)} = sprintf('Crystal coordinate system: \n\n     [a,b,c] = [%g,%g,%g]\n\n',minabc(1),minabc(2),minabc(3));
                cellArrayText{7+7*(i-1)} = sprintf('     [alpha,beta,gamma] = [%g,%g,%g]\n\n\n\n',minabg(1),minabg(2),minabg(3));


            end
            
            app.CurrentstateSingleCrystalElasticityTextArea.Value = cellArrayText;


        end

        % Button pushed function: ClearCheckPhasesButton
        function ClearCheckPhasesButtonPushed(app, event)
            app.CurrentstateSingleCrystalElasticityTextArea.Value = '';
        end

        % Button pushed function: CheckGrainButton
        function CheckGrainButtonPushed(app, event)
            numPhase = length(fieldnames(app.modelSC.Phase));
%             cellArrayText = cell(1,9*numPhase);
            n = 1;
            for i = 1:numPhase
                minName = cell2mat(app.modelSC.PhaseList(i,1));  
                if isfield(app.modelSC.Phase.(minName),'Ofile')
                    minNo = app.modelSC.Phase.(minName).No;
                    minOfile = app.modelSC.Phase.(minName).Ofile;
                    minCSDropDown = cell2mat(app.CrystalsymmetryDropDown.Items(2+app.modelSC.Phase.(minName).isymm));
                    minX1opt = ['X1: ',cell2mat(app.X1DropDown.Items(1+app.modelSC.Phase.(minName).ixoptm))];
                    minX3opt = ['X3: ',cell2mat(app.X3DropDown.Items(1+app.modelSC.Phase.(minName).izoptm))];
                    if app.modelSC.Phase.(minName).ixoptm == 1
                        minX1 = ['[',num2str(app.modelSC.Phase.(minName).x1e(1)),',',num2str(app.modelSC.Phase.(minName).x1e(2)),',',num2str(app.modelSC.Phase.(minName).x1e(3)),']'];
                    elseif app.modelSC.Phase.(minName).ixoptm == 2
                        minX1 = ['(',num2str(app.modelSC.Phase.(minName).x1e(1)),',',num2str(app.modelSC.Phase.(minName).x1e(2)),',',num2str(app.modelSC.Phase.(minName).x1e(3)),')'];
                    end                
                    if app.modelSC.Phase.(minName).izoptm == 1
                        minX3 = ['[',num2str(app.modelSC.Phase.(minName).x3e(1)),',',num2str(app.modelSC.Phase.(minName).x3e(2)),',',num2str(app.modelSC.Phase.(minName).x3e(3)),']'];
                    elseif app.modelSC.Phase.(minName).ixoptm == 2
                        minX3 = ['(',num2str(app.modelSC.Phase.(minName).x3e(1)),',',num2str(app.modelSC.Phase.(minName).x3e(2)),',',num2str(app.modelSC.Phase.(minName).x3e(3)),')'];
                    end      
                    minGrainShape = cell2mat(app.GrainShapeDropDown.Items(1+app.modelSC.Phase.(minName).ioptel));
                    minGrainOrientation = cell2mat(app.OrientationEllipsoidDropDown.Items(1+app.modelSC.Phase.(minName).ioptoe));
                    minA1A2A3 = ['[A1,A2,A3] = [',num2str(app.modelSC.Phase.(minName).axis(1)),',',num2str(app.modelSC.Phase.(minName).axis(2)),',',num2str(app.modelSC.Phase.(minName).axis(3)),']'];
                    minA1AZINC = ['A1: AZ = ',num2str(app.modelSC.Phase.(minName).al),char(176),', INC = ',num2str(app.modelSC.Phase.(minName).xl),char(176)];
                    minA3AZINC = ['A3: AZ = ',num2str(app.modelSC.Phase.(minName).af),char(176),', INC = ',num2str(app.modelSC.Phase.(minName).xf),char(176)];
                    
                    cellArrayText{1+9*(n-1)} = sprintf('No. %g \n\n',minNo);
                    cellArrayText{2+9*(n-1)} = sprintf('1. Name: %s \n\n',minName);
                    cellArrayText{3+9*(n-1)} = sprintf('2. Euler data file: %s \n\n',minOfile);
                    cellArrayText{4+9*(n-1)} = sprintf('3. Crystal symmetry: %s \n\n',minCSDropDown);
                    cellArrayText{5+9*(n-1)} = sprintf('4. Crystal reference direction: \n\n    %s  %s \n\n    %s  %s \n\n',minX1opt,minX1,minX3opt,minX3);
                    cellArrayText{6+9*(n-1)} = sprintf('5. Grain shape: %s \n\n',minGrainShape);
                    if strcmp(minGrainShape,'2 = Ellipsoid')
                        cellArrayText{7+9*(n-1)} = sprintf('6. Orientation of ellipsoid semi-axes: \n\n    %s \n\n',minGrainOrientation);
                        cellArrayText{8+9*(n-1)} = sprintf('7. Ellipsoid %s \n\n',minA1A2A3);
                        if strcmp(minGrainOrientation,'1 = // tensor axes X1, X2, X3 of each crystal (A1//X1, A2//X2, A3//X3)')
                            cellArrayText{9+9*(n-1)} = sprintf(' \n'); 
                        else
                            cellArrayText{9+9*(n-1)} = sprintf('8. Azimuth and inclination of A1 and A3: \n\n    %s \n\n    %s \n\n',minA1AZINC,minA3AZINC);                
                        end
                                        
                    else 
                        cellArrayText{7+9*(n-1)} = sprintf(' \n');
                        cellArrayText{8+9*(n-1)} = sprintf(' \n');
                        cellArrayText{9+9*(n-1)} = sprintf(' \n');                

                    end
                    n = n + 1;
                end   
            end
            app.CurrentstateGrainShapeOrientationTextArea.Value = cellArrayText;
            
        end

        % Button pushed function: ClearCheckGrainButton
        function ClearCheckGrainButtonPushed(app, event)
            app.CurrentstateGrainShapeOrientationTextArea.Value = '';
            
        end

        % Button pushed function: SaveGrainShapeOrientationButton
        function SaveGrainShapeOrientationButtonPushed(app, event)
            minName = app.NameGrainShapeOrientationEditField.Value;
            
            % Evaluate errors
            if ~isfield(app.modelSC.Phase,minName) 
                uialert(app.UIFigure,['Error: Single crystal elasticity for ',minName,' is not specified.'],'Error');
            end
            
            if isempty(app.NameGrainShapeOrientationEditField.Value)
                uialert(app.UIFigure,'Error: Name of phase is not specified.','Error');
            end
            
            if isempty(app.EulerdatafileEditField.Value)
                uialert(app.UIFigure,'Error: Euler data file for ',minName,' is not specified.','Error');
            end
            
            str_Crystalsymmetry = split(app.CrystalsymmetryDropDown.Value);            
            if isnan(str2double(str_Crystalsymmetry(1)))
                uialert(app.UIFigure,['Error: Crystal symmetry for ',minName,' is not selected.'],'Error');
            end

            str_X1 = split(app.X1DropDown.Value);            
            if isnan(str2double(str_X1(1)))
                uialert(app.UIFigure,['Error: Crystal reference direction X1 for ',minName,' is not selected.'],'Error');
            end
            
            if isempty(app.X1ind1EditField.Value) || isempty(app.X1ind2EditField.Value) || isempty(app.X1ind3EditField.Value)
                uialert(app.UIFigure,['Error: X1 indices for ',minName,' is not specified.'],'Error');  
            end           
            
            str_X3 = split(app.X3DropDown.Value);             
            if isnan(str2double(str_X3(1)))
                uialert(app.UIFigure,['Error: Crystal reference direction X3 for ',minName,' is not selected.'],'Error');
            end
            
            if isempty(app.X3ind1EditField.Value) || isempty(app.X3ind2EditField.Value) || isempty(app.X3ind3EditField.Value)
                uialert(app.UIFigure,['Error: X3 indices for ',minName,' is not specified.'],'Error'); 
            end
            
            str_Grainshape = split(app.GrainShapeDropDown.Value);
            if isnan(str2double(str_Grainshape(1)))
                uialert(app.UIFigure,['Error: Grain shape for ',minName,' is not selected.'],'Error');                
            end
          
            if ~strcmp(app.OrientationEllipsoidDropDown.Enable,'off')
                str_Orientation = split(app.OrientationEllipsoidDropDown.Value);
                if isnan(str2double(str_Orientation(1)))
                    uialert(app.UIFigure,['Error: Orientation of ellipsoid semi-axes for ',minName,' is not selected.'],'Error');
                end
            end
            
            A1off = strcmp(app.EllipsoidA1EditField.Enable,'off');
            A2off = strcmp(app.EllipsoidA2EditField.Enable,'off');
            A3off = strcmp(app.EllipsoidA3EditField.Enable,'off');
            if A1off ~= 1 && A2off ~= 1 && A3off ~= 1
                if isempty(app.EllipsoidA1EditField.Value) || isempty(app.EllipsoidA2EditField.Value) || isempty(app.EllipsoidA3EditField.Value)                
                    uialert(app.UIFigure,['Error: Ellipsoid semi-axes lengths (A1,A2,A3) for ',minName,' are not specified.'],'Error');               
                end  
            end
            
            A1AZoff = strcmp(app.A1AZEditField.Enable,'off');
            A1INCoff = strcmp(app.A1INCEditField.Enable,'off');
            A3AZoff = strcmp(app.A3AZEditField.Enable,'off');
            A3INCoff = strcmp(app.A3INCEditField.Enable,'off');
            if A1AZoff ~= 1 && A1INCoff ~= 1 && A3AZoff ~= 1 && A3INCoff ~= 1
                if isempty(app.A1AZEditField.Value) || isempty(app.A1INCEditField.Value) || isempty(app.A3AZEditField.Value) || isempty(app.A3INCEditField.Value)
                    uialert(app.UIFigure,['Error: Azimuth and inclination of A1 and A3 for ',minName,' are not specified.'],'Error');
                end               
            end

            app.modelSC.dir_Input  = deblank(app.AnisEulerSCpathEditField.Value);
            app.modelSC.dir_Output = deblank(app.AnisEulerSCpathEditField.Value);
            app.modelSC.Title = deblank(app.TitleEditField.Value);             
            app.modelSC.Phase.(minName).Ofile   = app.EulerdatafileEditField.Value; % Ofile: Euler data file
            app.modelSC.Phase.(minName).isymm   = str2double(str_Crystalsymmetry(1));
            app.modelSC.Phase.(minName).ixoptm  = str2double(str_X1(1)); 
            app.modelSC.Phase.(minName).x1e(1)  = str2double(app.X1ind1EditField.Value);
            app.modelSC.Phase.(minName).x1e(2)  = str2double(app.X1ind2EditField.Value);
            app.modelSC.Phase.(minName).x1e(3)  = str2double(app.X1ind3EditField.Value);  
            app.modelSC.Phase.(minName).izoptm  = str2double(str_X3(1)); 
            app.modelSC.Phase.(minName).x3e(1)  = str2double(app.X3ind1EditField.Value);
            app.modelSC.Phase.(minName).x3e(2)  = str2double(app.X3ind2EditField.Value);
            app.modelSC.Phase.(minName).x3e(3)  = str2double(app.X3ind3EditField.Value);           
            app.modelSC.Phase.(minName).ioptel  = str2double(str_Grainshape(1));  
            if app.modelSC.Phase.(minName).ioptel == 1
                app.modelSC.Phase.(minName).ioptoe = 1;
                app.modelSC.Phase.(minName).axis(1) = 1;
                app.modelSC.Phase.(minName).axis(2) = 1;
                app.modelSC.Phase.(minName).axis(3) = 1;
                app.modelSC.Phase.(minName).al = NaN(1);
                app.modelSC.Phase.(minName).xl = NaN(1);
                app.modelSC.Phase.(minName).af = NaN(1);
                app.modelSC.Phase.(minName).xf = NaN(1);
            elseif app.modelSC.Phase.(minName).ioptel == 2
                app.modelSC.Phase.(minName).ioptoe  = str2double(str_Orientation(1)); 
                if app.modelSC.Phase.(minName).ioptoe == 1
                    app.modelSC.Phase.(minName).axis(1) = str2double(app.EllipsoidA1EditField.Value);
                    app.modelSC.Phase.(minName).axis(2) = str2double(app.EllipsoidA2EditField.Value);
                    app.modelSC.Phase.(minName).axis(3) = str2double(app.EllipsoidA3EditField.Value);  
                    app.modelSC.Phase.(minName).al = NaN(1);
                    app.modelSC.Phase.(minName).xl = NaN(1);
                    app.modelSC.Phase.(minName).af = NaN(1);
                    app.modelSC.Phase.(minName).xf = NaN(1);  
                elseif app.modelSC.Phase.(minName).ioptoe == 2
                    app.modelSC.Phase.(minName).axis(1) = str2double(app.EllipsoidA1EditField.Value);
                    app.modelSC.Phase.(minName).axis(2) = str2double(app.EllipsoidA2EditField.Value);
                    app.modelSC.Phase.(minName).axis(3) = str2double(app.EllipsoidA3EditField.Value);           
                    app.modelSC.Phase.(minName).al = str2double(app.A1AZEditField.Value);  % al: A1 AZ Azimuth of ellipsoid semi-axis
                    app.modelSC.Phase.(minName).xl = str2double(app.A1INCEditField.Value); % xl: A1 INC Inclination of ellipsoid semi-axis           
                    app.modelSC.Phase.(minName).af = str2double(app.A3AZEditField.Value);  % af: A3 AZ Azimuth of ellipsoid semi-axis
                    app.modelSC.Phase.(minName).xf = str2double(app.A3INCEditField.Value); % xf: A3 INC Inclination of ellipsoid semi-axis                                   
                end
            end
            
            
            numPhase = app.modelSC.nPhase;
            n = 0;
            for i = 1:numPhase
                iName = cell2mat(app.modelSC.PhaseList(i,1));  
                if isfield(app.modelSC.Phase.(iName),'Ofile')
                    n = n+1;
                end
            end

            if n == app.modelSC.nPhase
                uialert(app.UIFigure,'All phases are specified and saved!','Success','Icon','Success');
            elseif n < app.modelSC.nPhase
                uialert(app.UIFigure,'Number of current phases is smaller than total number!','Warning','Icon','Warning');
            elseif n > app.modelSC.nPhase
                uialert(app.UIFigure,'Number of current phases is larger than total number!','Warning','Icon','Warning');
            end
            
            ModelSC = app.modelSC;
            app.filename = [deblank(app.AnisEulerSCpathEditField.Value),deblank(app.TitleEditField.Value),'_Inputs_ModelSC.mat'];
            save(app.filename,'ModelSC');
            
        end

        % Button pushed function: LoadSingleCrystalPropButton
        function LoadSingleCrystalPropButtonPushed(app, event)
            
            minName = app.LoadSingleCrystalPropNameEditField.Value;
            
            if isfield(app.modelSC.Phase,minName)
                app.HowmanyphasesEditField.Value = app.modelSC.nPhase;
                app.NoEditField.Value = num2str(app.modelSC.Phase.(minName).No);
                app.NameEditField.Value = minName;
                app.VolumefractionEditField.Value = num2str(app.modelSC.Phase.(minName).vf);
                app.ReferenceEditField.Value = cell2mat(app.modelSC.Phase.(minName).reference);
                app.Densitygcm3EditField.Value = num2str(app.modelSC.Phase.(minName).rho);
                app.crystalSymmetryEditField.Value = cell2mat(app.modelSC.Phase.(minName).crystalSymmetry);
                app.crystalReferenceFrameEditField.Value = cell2mat(app.modelSC.Phase.(minName).crystalReferenceFrame);
                app.aEditField.Value = num2str(app.modelSC.Phase.(minName).abc(1));
                app.bEditField.Value = num2str(app.modelSC.Phase.(minName).abc(2));
                app.cEditField.Value = num2str(app.modelSC.Phase.(minName).abc(3));
                app.alphaEditField.Value = num2str(app.modelSC.Phase.(minName).abg(1));
                app.betaEditField.Value = num2str(app.modelSC.Phase.(minName).abg(2));
                app.gammaEditField.Value = num2str(app.modelSC.Phase.(minName).abg(3));
                app.C11EditField.Value = num2str(app.modelSC.Phase.(minName).M(1,1));
                app.C12EditField.Value = num2str(app.modelSC.Phase.(minName).M(1,2));
                app.C13EditField.Value = num2str(app.modelSC.Phase.(minName).M(1,3));
                app.C14EditField.Value = num2str(app.modelSC.Phase.(minName).M(1,4));
                app.C15EditField.Value = num2str(app.modelSC.Phase.(minName).M(1,5));
                app.C16EditField.Value = num2str(app.modelSC.Phase.(minName).M(1,6));
                app.C22EditField.Value = num2str(app.modelSC.Phase.(minName).M(2,2));
                app.C23EditField.Value = num2str(app.modelSC.Phase.(minName).M(2,3));
                app.C24EditField.Value = num2str(app.modelSC.Phase.(minName).M(2,4));
                app.C25EditField.Value = num2str(app.modelSC.Phase.(minName).M(2,5));
                app.C26EditField.Value = num2str(app.modelSC.Phase.(minName).M(2,6));
                app.C33EditField.Value = num2str(app.modelSC.Phase.(minName).M(3,3));
                app.C34EditField.Value = num2str(app.modelSC.Phase.(minName).M(3,4));
                app.C35EditField.Value = num2str(app.modelSC.Phase.(minName).M(3,5));
                app.C36EditField.Value = num2str(app.modelSC.Phase.(minName).M(3,6));
                app.C44EditField.Value = num2str(app.modelSC.Phase.(minName).M(4,4));
                app.C45EditField.Value = num2str(app.modelSC.Phase.(minName).M(4,5));
                app.C46EditField.Value = num2str(app.modelSC.Phase.(minName).M(4,6));                
                app.C55EditField.Value = num2str(app.modelSC.Phase.(minName).M(5,5));
                app.C56EditField.Value = num2str(app.modelSC.Phase.(minName).M(5,6));                  
                app.C66EditField.Value = num2str(app.modelSC.Phase.(minName).M(6,6));   
                
            end
            
            
        end

        % Button pushed function: LoadGrainShapeOrientationButton
        function LoadGrainShapeOrientationButtonPushed(app, event)
            
            minName = app.LoadGrainShapeOrientationNameEditField.Value;
            
            if isfield(app.modelSC.Phase.(minName),'Ofile')
                
                app.NameGrainShapeOrientationEditField.Value = minName;
                app.EulerdatafileEditField.Value = app.modelSC.Phase.(minName).Ofile; % Ofile: Euler data file                
                app.CrystalsymmetryDropDown.Value = app.CrystalsymmetryDropDown.Items(2+app.modelSC.Phase.(minName).isymm);                
                app.X1DropDown.Value = app.X1DropDown.Items(1+app.modelSC.Phase.(minName).ixoptm);
                app.X3DropDown.Value = app.X3DropDown.Items(1+app.modelSC.Phase.(minName).izoptm);
                app.X1ind1EditField.Value = num2str(app.modelSC.Phase.(minName).x1e(1));
                app.X1ind2EditField.Value = num2str(app.modelSC.Phase.(minName).x1e(2));
                app.X1ind3EditField.Value = num2str(app.modelSC.Phase.(minName).x1e(3));                
                app.X3ind1EditField.Value = num2str(app.modelSC.Phase.(minName).x3e(1));
                app.X3ind2EditField.Value = num2str(app.modelSC.Phase.(minName).x3e(2));
                app.X3ind3EditField.Value = num2str(app.modelSC.Phase.(minName).x3e(3));
                app.GrainShapeDropDown.Value = app.GrainShapeDropDown.Items(1+app.modelSC.Phase.(minName).ioptel);
                
                app.OrientationEllipsoidDropDown.Value = app.OrientationEllipsoidDropDown.Items(1+app.modelSC.Phase.(minName).ioptoe);
                if strcmp(app.GrainShapeDropDown.Value,'1 = Sphere')
                    app.OrientationEllipsoidDropDown.Enable = 'off';
                    app.EllipsoidA1EditField.Enable = 'off';
                    app.EllipsoidA2EditField.Enable = 'off';
                    app.EllipsoidA3EditField.Enable = 'off';
                    app.A1AZEditField.Enable = 'off';
                    app.A1INCEditField.Enable = 'off';
                    app.A3AZEditField.Enable = 'off';
                    app.A3INCEditField.Enable = 'off';
                elseif strcmp(app.GrainShapeDropDown.Value,'2 = Ellipsoid')
                    
                    if strcmp(app.OrientationEllipsoidDropDown.Value,'1 = // tensor axes X1, X2, X3 of each crystal (A1//X1, A2//X2, A3//X3)')
                        
                        app.EllipsoidA1EditField.Value = num2str(app.modelSC.Phase.(minName).axis(1));
                        app.EllipsoidA2EditField.Value = num2str(app.modelSC.Phase.(minName).axis(2));
                        app.EllipsoidA3EditField.Value = num2str(app.modelSC.Phase.(minName).axis(3));
                        app.A1AZEditField.Value  = '';
                        app.A1INCEditField.Value = '';
                        app.A3AZEditField.Value  = '';
                        app.A3INCEditField.Value = '';
                        app.OrientationEllipsoidDropDown.Enable = 'on';
                        app.EllipsoidA1EditField.Enable = 'on';
                        app.EllipsoidA2EditField.Enable = 'on';
                        app.EllipsoidA3EditField.Enable = 'on';
                        app.A1AZEditField.Enable = 'off';
                        app.A1INCEditField.Enable = 'off';
                        app.A3AZEditField.Enable = 'off';
                        app.A3INCEditField.Enable = 'off';
                    elseif strcmp(app.OrientationEllipsoidDropDown.Value,'2 = // user defined orientation in PF coordinate (e.g., fluids)')
                        
                        app.EllipsoidA1EditField.Value = num2str(app.modelSC.Phase.(minName).axis(1));
                        app.EllipsoidA2EditField.Value = num2str(app.modelSC.Phase.(minName).axis(2));
                        app.EllipsoidA3EditField.Value = num2str(app.modelSC.Phase.(minName).axis(3));
                        app.A1AZEditField.Value  = num2str(app.modelSC.Phase.(minName).al);
                        app.A1INCEditField.Value = num2str(app.modelSC.Phase.(minName).xl);
                        app.A3AZEditField.Value  = num2str(app.modelSC.Phase.(minName).af);
                        app.A3INCEditField.Value = num2str(app.modelSC.Phase.(minName).xf);
                        app.OrientationEllipsoidDropDown.Enable = 'on';
                        app.EllipsoidA1EditField.Enable = 'on';
                        app.EllipsoidA2EditField.Enable = 'on';
                        app.EllipsoidA3EditField.Enable = 'on';
                        app.A1AZEditField.Enable = 'on';
                        app.A1INCEditField.Enable = 'on';
                        app.A3AZEditField.Enable = 'on';
                        app.A3INCEditField.Enable = 'on';                                                 
                    end
                end                   
            end
            
        end

        % Button pushed function: ClearGrainShapeOrientationButton
        function ClearGrainShapeOrientationButtonPushed(app, event)
            app.NameGrainShapeOrientationEditField.Value = '';
            app.EulerdatafileEditField.Value = '';           
            app.CrystalsymmetryDropDown.Value = app.CrystalsymmetryDropDown.Items(1);
            app.X1DropDown.Value = app.X1DropDown.Items(1);
            app.X1ind1EditField.Value = '';
            app.X1ind2EditField.Value = '';
            app.X1ind3EditField.Value = '';
            app.X3DropDown.Value = app.X3DropDown.Items(1);
            app.X3ind1EditField.Value = '';
            app.X3ind2EditField.Value = '';
            app.X3ind3EditField.Value = '';
            app.GrainShapeDropDown.Value = app.GrainShapeDropDown.Items(1);
            app.OrientationEllipsoidDropDown.Value = app.OrientationEllipsoidDropDown.Items(1);
            app.EllipsoidA1EditField.Value = '';
            app.EllipsoidA2EditField.Value = '';
            app.EllipsoidA3EditField.Value = '';
            app.A1AZEditField.Value = '';
            app.A1INCEditField.Value = '';
            app.A3AZEditField.Value = '';
            app.A3INCEditField.Value = '';
            app.LoadGrainShapeOrientationNameEditField.Value = '';
            
        end

        % Button pushed function: ShowButton
        function ShowButtonPushed(app, event)
            SummaryText{1} = sprintf('Input data of a polycrystalline aggregate for modeling self-consistent approximation \n\n');
%             modelSC_Title = app.modelSC.Title;
%             TitleEditField_Value = app.TitleEditField.Value;
%             if strcmp(modelSC_Title,TitleEditField_Value)
%                 
%             end
            SummaryText{2} = sprintf('Title: %s \n\n',app.modelSC.Title);             
            SummaryText{3} = sprintf('File name: %s_Inputs_ModelSC.mat \n\n',app.modelSC.Title);    
            SummaryText{4} = sprintf('File directory (full path): %s \n\n',app.modelSC.dir_Output);
            SummaryText{5} = sprintf('Self-consistent scheme: %s \n\n',cell2mat(app.SCschemeDropDown.Items(1+app.modelSC.ioptsc)));
            SummaryText{6} = sprintf('Total number of phases: %g \n\n',app.modelSC.nPhase);
            n = 1;
            for i = 1:app.modelSC.nPhase
                minName = cell2mat(app.modelSC.PhaseList(i));
                SummaryText{7+(n-1)} = sprintf(['Phase %g    %s    Volume fraction = %g    Density = %6.4f (g/cm',char(179),')\n\n'],app.modelSC.Phase.(minName).No,minName,app.modelSC.Phase.(minName).vf,app.modelSC.Phase.(minName).rho);
                n = n + 1;
            end
            
            app.SummaryTextArea.Value = SummaryText;
            
            
        end

        % Button pushed function: LoadSetupButton
        function LoadSetupButtonPushed(app, event)
            app.filename = [deblank(app.AnisEulerSCpathEditField.Value),deblank(app.TitleEditField.Value),'_Inputs_ModelSC.mat'];
            if exist(app.filename,"file") == 2
                load(app.filename,"ModelSC");
                app.modelSC = ModelSC;
                app.SCschemeDropDown.Value = app.SCschemeDropDown.Items(1+app.modelSC.ioptsc);
                app.InitialvalueforSCschemeDropDown.Value = app.InitialvalueforSCschemeDropDown.Items(1+app.modelSC.isch);
                app.FormatofEulerfileDropDown.Value = app.FormatofEulerfileDropDown.Items(1+app.modelSC.ioptn);
                app.OriginofEulerfileDropDown.Value = app.OriginofEulerfileDropDown.Items(1+app.modelSC.ieuler);
                app.XsAzEditField.Value = app.modelSC.XsAz;
                app.MaxSCiterationsEditField.Value = app.modelSC.nsc;
                app.MaxGreenstensorEditField.Value = app.modelSC.errmax;
                app.MaxSCconvergenceEditField.Value = app.modelSC.conmax;
            else
                ModelSC = app.modelSC;
                save(app.filename,'ModelSC');
                
            end
        end

        % Value changed function: TitleEditField
        function ChangedTitleEditField(app, event)
         
            %app.filename = [deblank(app.AnisEulerSCpathEditField.Value),deblank(app.TitleEditField.Value),'_Inputs_ModelSC.mat'];
        end

        % Value changed function: GrainShapeDropDown
        function GrainShapeDropDownValueChanged(app, event)
            
            if strcmp(app.GrainShapeDropDown.Value,'1 = Sphere')
                app.OrientationEllipsoidDropDown.Enable = 'off';
                app.EllipsoidA1EditField.Enable = 'off';
                app.EllipsoidA2EditField.Enable = 'off';
                app.EllipsoidA3EditField.Enable = 'off';
                app.A1AZEditField.Enable = 'off';
                app.A1INCEditField.Enable = 'off';
                app.A3AZEditField.Enable = 'off';
                app.A3INCEditField.Enable = 'off';   
            elseif strcmp(app.GrainShapeDropDown.Value,'2 = Ellipsoid')
                app.OrientationEllipsoidDropDown.Value = 'Select';
                app.EllipsoidA1EditField.Value = '';
                app.EllipsoidA2EditField.Value = '';
                app.EllipsoidA3EditField.Value = '';
                app.A1AZEditField.Value = '';
                app.A1INCEditField.Value = '';
                app.A3AZEditField.Value = '';
                app.A3INCEditField.Value = '';   
                app.OrientationEllipsoidDropDown.Enable = 'on';
                app.EllipsoidA1EditField.Enable = 'on';
                app.EllipsoidA2EditField.Enable = 'on';
                app.EllipsoidA3EditField.Enable = 'on';
                app.A1AZEditField.Enable = 'on';
                app.A1INCEditField.Enable = 'on';
                app.A3AZEditField.Enable = 'on';
                app.A3INCEditField.Enable = 'on';                 
            end
        end

        % Value changed function: OrientationEllipsoidDropDown
        function OrientationEllipsoidDropDownValueChanged(app, event)

            if strcmp(app.OrientationEllipsoidDropDown.Value,'1 = // tensor axes X1, X2, X3 of each crystal (A1//X1, A2//X2, A3//X3)') 
                app.EllipsoidA1EditField.Enable = 'on';
                app.EllipsoidA2EditField.Enable = 'on';
                app.EllipsoidA3EditField.Enable = 'on';
                app.A1AZEditField.Enable = 'off';
                app.A1INCEditField.Enable = 'off';
                app.A3AZEditField.Enable = 'off';
                app.A3INCEditField.Enable = 'off';        
            else
                app.EllipsoidA1EditField.Enable = 'on';
                app.EllipsoidA2EditField.Enable = 'on';
                app.EllipsoidA3EditField.Enable = 'on';
                app.A1AZEditField.Enable = 'on';
                app.A1INCEditField.Enable = 'on';
                app.A3AZEditField.Enable = 'on';
                app.A3INCEditField.Enable = 'on';           
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.SetupTab = uitab(app.TabGroup);
            app.SetupTab.Title = 'Setup';
            app.InitialvalueforSCschemeDropDownLabel = uilabel(app.SetupTab);
            app.InitialvalueforSCschemeDropDownLabel.Text = 'Initial value for SC scheme';
            app.InitialvalueforSCschemeDropDown = uidropdown(app.SetupTab);
            app.InitialvalueforSCschemeDropDown.Items = {'Select', '1 = Voigt (best when fluids present)', '2 = Reuss', '3 = Voigt-Reuss-Hill'};
            app.InitialvalueforSCschemeDropDown.Value = 'Select';
            app.SCschemeDropDown_2Label = uilabel(app.SetupTab);
            app.SCschemeDropDown_2Label.Text = 'SC scheme';
            app.SCschemeDropDown = uidropdown(app.SetupTab);
            app.SCschemeDropDown.Items = {'Select', '1 = Mechanical - Willis 1977 (most stable)', '2 = Scattering - Gubernatis & Krumhansl 1975'};
            app.SCschemeDropDown.Value = 'Select';
            app.FormatofEulerfileDropDownLabel = uilabel(app.SetupTab);
            app.FormatofEulerfileDropDownLabel.Text = 'Format of Euler file';
            app.FormatofEulerfileDropDown = uidropdown(app.SetupTab);
            app.FormatofEulerfileDropDown.Items = {'Select', '1 = phi1, PHI, phi2', '2 = no, phi1, PHI, phi2', '3 = phi1, PHI, phi2, volume fraction', '4 = no, phi1, PHI, phi2, volume fraction'};
            app.FormatofEulerfileDropDown.Value = 'Select';
            app.OriginofEulerfileDropDownLabel = uilabel(app.SetupTab);
            app.OriginofEulerfileDropDownLabel.Text = 'Origin of Euler file';
            app.OriginofEulerfileDropDown = uidropdown(app.SetupTab);
            app.OriginofEulerfileDropDown.Items = {'Select', '1 = Universal stage (UMII)', '2 = EBSD (Channel+)'};
            app.OriginofEulerfileDropDown.Value = 'Select';
            app.Xsofazimuthinrange0to360degreesEditFieldLabel = uilabel(app.SetupTab);
            app.Xsofazimuthinrange0to360degreesEditFieldLabel.Text = 'Xs of azimuth (in range 0 to 360 degrees)';
            app.XsAzEditField = uieditfield(app.SetupTab, 'numeric');
            app.AnisEulerSCpathEditFieldLabel = uilabel(app.SetupTab);
            app.AnisEulerSCpathEditFieldLabel.Text = 'AnisEulerSC path';
            app.AnisEulerSCpathEditField = uieditfield(app.SetupTab, 'text');
            app.TitleEditFieldLabel = uilabel(app.SetupTab);
            app.TitleEditFieldLabel.Text = 'Title';
            app.TitleEditField = uieditfield(app.SetupTab, 'text');
            app.TitleEditField.ValueChangedFcn = createCallbackFcn(app, @ChangedTitleEditField, true);
            app.MaximumnumberofSCiterationseg40EditFieldLabel = uilabel(app.SetupTab);
            app.MaximumnumberofSCiterationseg40EditFieldLabel.Text = 'Maximum number of SC iterations (e.g., 40)';
            app.MaxSCiterationsEditField = uieditfield(app.SetupTab, 'numeric');
            app.MaximumerrorforGreenstensoreg01EditFieldLabel = uilabel(app.SetupTab);
            app.MaximumerrorforGreenstensoreg01EditFieldLabel.Text = 'Maximum error (%) for Green''s tensor (e.g., 0.1)';
            app.MaxGreenstensorEditField = uieditfield(app.SetupTab, 'numeric');
            app.MaximumerrorforSCconvergenceeg01EditFieldLabel = uilabel(app.SetupTab);
            app.MaximumerrorforSCconvergenceeg01EditFieldLabel.Text = 'Maximum error (%) for SC convergence (e.g., 0.1)';
            app.MaxSCconvergenceEditField = uieditfield(app.SetupTab, 'numeric');
            app.SaveSetupButton = uibutton(app.SetupTab, 'push');
            app.SaveSetupButton.ButtonPushedFcn = createCallbackFcn(app, @SaveSetupButtonPushed, true);
            app.SaveSetupButton.Text = 'Save';
            app.ClearSetupButton = uibutton(app.SetupTab, 'push');
            app.ClearSetupButton.ButtonPushedFcn = createCallbackFcn(app, @ClearSetupButtonPushed, true);
            app.ClearSetupButton.Text = 'Clear';
            app.LoadSetupButton = uibutton(app.SetupTab, 'push');
            app.LoadSetupButton.ButtonPushedFcn = createCallbackFcn(app, @LoadSetupButtonPushed, true);
            app.LoadSetupButton.Text = 'Load file';
            app.NoteInputparametersarestoredinafilenamedTitle_ModelSCmatLabel = uilabel(app.SetupTab);
            app.NoteInputparametersarestoredinafilenamedTitle_ModelSCmatLabel.Text = '*Note: Input parameters are stored in a file named ''Title''_Inputs_ModelSC.mat.';
            app.NoteInputparametersarestoredinafilenamedTitle_ModelSCmatLabel_2 = uilabel(app.SetupTab);
            app.NoteInputparametersarestoredinafilenamedTitle_ModelSCmatLabel_2.Text = '*Note: e.g., /Users/ekim/';
            app.Inputs_ModelSCmatLabel = uilabel(app.SetupTab);
            app.Inputs_ModelSCmatLabel.Text = '_Inputs_ModelSC.mat';
            app.SinglecrystalpropertiesTab = uitab(app.TabGroup);
            app.SinglecrystalpropertiesTab.Title = 'Single-crystal properties';
            app.CurrentstateTextAreaLabel = uilabel(app.SinglecrystalpropertiesTab);
            app.CurrentstateTextAreaLabel.Text = 'Current state';
            app.CurrentstateSingleCrystalElasticityTextArea = uitextarea(app.SinglecrystalpropertiesTab);
            app.CurrentstateSingleCrystalElasticityTextArea.Editable = 'off';
            app.PhasePanel = uipanel(app.SinglecrystalpropertiesTab);
            app.PhasePanel.Title = 'Phase';
            app.SinglecrystalelasticstiffnesstensorinVoigtmatrixCijGPaLabel = uilabel(app.PhasePanel);
            app.SinglecrystalelasticstiffnesstensorinVoigtmatrixCijGPaLabel.Text = 'Single-crystal elastic stiffness tensor in Voigt matrix, Cij (GPa)';
            app.CrystalsymmetryEditFieldLabel = uilabel(app.PhasePanel);
            app.CrystalsymmetryEditFieldLabel.Text = 'Crystal symmetry';
            app.crystalSymmetryEditField = uieditfield(app.PhasePanel, 'text');
            app.CrystalreferenceframeEditFieldLabel = uilabel(app.PhasePanel);
            app.CrystalreferenceframeEditFieldLabel.Text = 'Crystal reference frame';
            app.crystalReferenceFrameEditField = uieditfield(app.PhasePanel, 'text');
            app.ReferenceEditFieldLabel = uilabel(app.PhasePanel);
            app.ReferenceEditFieldLabel.Text = 'Reference';
            app.ReferenceEditField = uieditfield(app.PhasePanel, 'text');
            app.SaveSingleCrystalElasticityButton = uibutton(app.PhasePanel, 'push');
            app.SaveSingleCrystalElasticityButton.ButtonPushedFcn = createCallbackFcn(app, @SaveSingleCrystalElasticityButtonPushed, true);
            app.SaveSingleCrystalElasticityButton.Text = 'Save';
            app.NameEditFieldLabel = uilabel(app.PhasePanel);
            app.NameEditFieldLabel.Text = 'Name';
            app.NameEditField = uieditfield(app.PhasePanel, 'text');
            app.ClearPhaseButton = uibutton(app.PhasePanel, 'push');
            app.ClearPhaseButton.ButtonPushedFcn = createCallbackFcn(app, @ClearPhaseButtonPushed, true);
            app.ClearPhaseButton.Text = 'Clear phase';
            app.C11EditField = uieditfield(app.PhasePanel, 'text');
            app.C12EditField = uieditfield(app.PhasePanel, 'text');
            app.C13EditField = uieditfield(app.PhasePanel, 'text');
            app.C14EditField = uieditfield(app.PhasePanel, 'text');
            app.C15EditField = uieditfield(app.PhasePanel, 'text');
            app.C22EditField = uieditfield(app.PhasePanel, 'text');
            app.C23EditField = uieditfield(app.PhasePanel, 'text');
            app.C24EditField = uieditfield(app.PhasePanel, 'text');
            app.C25EditField = uieditfield(app.PhasePanel, 'text');
            app.C16EditField = uieditfield(app.PhasePanel, 'text');
            app.C26EditField = uieditfield(app.PhasePanel, 'text');
            app.C33EditField = uieditfield(app.PhasePanel, 'text');
            app.C34EditField = uieditfield(app.PhasePanel, 'text');
            app.C35EditField = uieditfield(app.PhasePanel, 'text');
            app.C36EditField = uieditfield(app.PhasePanel, 'text');
            app.C44EditField = uieditfield(app.PhasePanel, 'text');
            app.C45EditField = uieditfield(app.PhasePanel, 'text');
            app.C46EditField = uieditfield(app.PhasePanel, 'text');
            app.C55EditField = uieditfield(app.PhasePanel, 'text');
            app.C56EditField = uieditfield(app.PhasePanel, 'text');
            app.C66EditField = uieditfield(app.PhasePanel, 'text');
            app.Densitygcm3EditFieldLabel = uilabel(app.PhasePanel);
            app.Densitygcm3EditFieldLabel.Text = 'Density (g/cm3)';
            app.Densitygcm3EditField = uieditfield(app.PhasePanel, 'text');
            app.crystalCoordinateSystemLabel = uilabel(app.PhasePanel);
            app.crystalCoordinateSystemLabel.Text = 'Crystal coordinate system';
            app.aEditFieldLabel = uilabel(app.PhasePanel);
            app.aEditFieldLabel.Text = 'a';
            app.aEditField = uieditfield(app.PhasePanel, 'text');
            app.bEditFieldLabel = uilabel(app.PhasePanel);
            app.bEditFieldLabel.Text = 'b';
            app.bEditField = uieditfield(app.PhasePanel, 'text');
            app.alphaEditFieldLabel = uilabel(app.PhasePanel);
            app.alphaEditFieldLabel.Text = 'alpha';
            app.alphaEditField = uieditfield(app.PhasePanel, 'text');
            app.betaEditFieldLabel = uilabel(app.PhasePanel);
            app.betaEditFieldLabel.Text = 'beta';
            app.betaEditField = uieditfield(app.PhasePanel, 'text');
            app.cEditFieldLabel = uilabel(app.PhasePanel);
            app.cEditFieldLabel.Text = 'c';
            app.cEditField = uieditfield(app.PhasePanel, 'text');
            app.gammaEditFieldLabel = uilabel(app.PhasePanel);
            app.gammaEditFieldLabel.Text = 'gamma';
            app.gammaEditField = uieditfield(app.PhasePanel, 'text');
            app.VolumefractionEditFieldLabel = uilabel(app.PhasePanel);
            app.VolumefractionEditFieldLabel.Text = 'Volume fraction';
            app.VolumefractionEditField = uieditfield(app.PhasePanel, 'text');
            app.NoEditFieldLabel = uilabel(app.PhasePanel);
            app.NoEditFieldLabel.Text = 'No.';
            app.NoEditField = uieditfield(app.PhasePanel, 'text');
            app.LoadSingleCrystalPropButton = uibutton(app.PhasePanel, 'push');
            app.LoadSingleCrystalPropButton.ButtonPushedFcn = createCallbackFcn(app, @LoadSingleCrystalPropButtonPushed, true);
            app.LoadSingleCrystalPropButton.Text = 'Load';
            app.NameEditField_2Label = uilabel(app.PhasePanel);
            app.NameEditField_2Label.Text = 'Name:';
            app.LoadSingleCrystalPropNameEditField = uieditfield(app.PhasePanel, 'text');
            app.CheckPhasesButton = uibutton(app.SinglecrystalpropertiesTab, 'push');
            app.CheckPhasesButton.ButtonPushedFcn = createCallbackFcn(app, @CheckPhasesButtonPushed, true);
            app.CheckPhasesButton.Text = 'Check phases';
            app.ClearCheckPhasesButton = uibutton(app.SinglecrystalpropertiesTab, 'push');
            app.ClearCheckPhasesButton.ButtonPushedFcn = createCallbackFcn(app, @ClearCheckPhasesButtonPushed, true);
            app.ClearCheckPhasesButton.Text = 'Clear';
            app.HowmanyphasesEditFieldLabel = uilabel(app.SinglecrystalpropertiesTab);
            app.HowmanyphasesEditFieldLabel.Text = 'How many phases ?';
            app.HowmanyphasesEditField = uieditfield(app.SinglecrystalpropertiesTab, 'numeric');
            app.GrainshapesandorientationsTab = uitab(app.TabGroup);
            app.GrainshapesandorientationsTab.Title = 'Grain shapes and orientations';
            app.CurrentstateTextArea_2Label = uilabel(app.GrainshapesandorientationsTab);
            app.CurrentstateTextArea_2Label.Text = 'Current state';
            app.CurrentstateGrainShapeOrientationTextArea = uitextarea(app.GrainshapesandorientationsTab);
            app.CurrentstateGrainShapeOrientationTextArea.Editable = 'off';
            app.CheckGrainButton = uibutton(app.GrainshapesandorientationsTab, 'push');
            app.CheckGrainButton.ButtonPushedFcn = createCallbackFcn(app, @CheckGrainButtonPushed, true);
            app.CheckGrainButton.Text = 'Check phases';
            app.ClearCheckGrainButton = uibutton(app.GrainshapesandorientationsTab, 'push');
            app.ClearCheckGrainButton.ButtonPushedFcn = createCallbackFcn(app, @ClearCheckGrainButtonPushed, true);
            app.ClearCheckGrainButton.Text = 'Clear';
            app.PhasePanel_2 = uipanel(app.GrainshapesandorientationsTab);
            app.PhasePanel_2.Title = 'Phase';
            app.EulerspaceforcrystalegtriclinicsamplesymmetryLabel = uilabel(app.PhasePanel_2);
            app.EulerspaceforcrystalegtriclinicsamplesymmetryLabel.Text = '3. Euler space for crystal (e.g., triclinic sample symmetry)';
            app.GrainshapeDropDownLabel = uilabel(app.PhasePanel_2);
            app.GrainshapeDropDownLabel.Text = '5. Grain shape';
            app.GrainShapeDropDown = uidropdown(app.PhasePanel_2);
            app.GrainShapeDropDown.Items = {'Select', '1 = Sphere', '2 = Ellipsoid'};
            app.GrainShapeDropDown.ValueChangedFcn = createCallbackFcn(app, @GrainShapeDropDownValueChanged, true);
            app.GrainShapeDropDown.Value = 'Select';
            app.X1DropDownLabel = uilabel(app.PhasePanel_2);
            app.X1DropDownLabel.Text = 'X1';
            app.X1DropDown = uidropdown(app.PhasePanel_2);
            app.X1DropDown.Items = {'Select', '1 = direction [UVW]', '2 = pole normal (HKL)'};
            app.X1DropDown.Value = 'Select';
            app.X3DropDownLabel = uilabel(app.PhasePanel_2);
            app.X3DropDownLabel.Text = 'X3';
            app.X3DropDown = uidropdown(app.PhasePanel_2);
            app.X3DropDown.Items = {'Select', '1 = direction [UVW]', '2 = pole normal (HKL)'};
            app.X3DropDown.Value = 'Select';
            app.NameLabel = uilabel(app.PhasePanel_2);
            app.NameLabel.Text = '1. Name';
            app.NameGrainShapeOrientationEditField = uieditfield(app.PhasePanel_2, 'text');
            app.EulerdatafileEditFieldLabel = uilabel(app.PhasePanel_2);
            app.EulerdatafileEditFieldLabel.Text = '2. Euler data file';
            app.EulerdatafileEditField = uieditfield(app.PhasePanel_2, 'text');
            app.AZINCLabel = uilabel(app.PhasePanel_2);
            app.AZINCLabel.Text = {'AZ: Azimuth of ellipsoid semi-axis'; 'INC: Inclination of ellipsoid semi-axis'};
            app.OrientationofellipsoidsemiaxesDropDownLabel = uilabel(app.PhasePanel_2);
            app.OrientationofellipsoidsemiaxesDropDownLabel.Text = '6. Orientation of ellipsoid semi-axes';
            app.OrientationEllipsoidDropDown = uidropdown(app.PhasePanel_2);
            app.OrientationEllipsoidDropDown.Items = {'Select', '1 = // tensor axes X1, X2, X3 of each crystal (A1//X1, A2//X2, A3//X3)', '2 = // user defined orientation in PF coordinate (e.g., fluids)'};
            app.OrientationEllipsoidDropDown.ValueChangedFcn = createCallbackFcn(app, @OrientationEllipsoidDropDownValueChanged, true);
            app.OrientationEllipsoidDropDown.Value = 'Select';
            app.GrainshapedefinitionLabel = uilabel(app.PhasePanel_2);
            app.GrainshapedefinitionLabel.Text = 'Ellipsoid semi-axes a1, a2, a3';
            app.GrainshapedefinitionLabel_2 = uilabel(app.PhasePanel_2);
            app.GrainshapedefinitionLabel_2.Text = {'Sphere: a1 = a2 = a3'; 'Oblate ellipsoid: a1 = a2 > a3'; 'Prolate ellipsoid: a1 = a2 < a3'};
            app.Label = uilabel(app.PhasePanel_2);
            app.Label.Text = {'4. User defined crystal reference directions (X1, X2, X3)'; '    (As X1, X2 and X3 must be orthogonal, only X1 and X3 need to be defined.)'};
            app.CrystalsymmetryDropDownLabel = uilabel(app.PhasePanel_2);
            app.CrystalsymmetryDropDownLabel.Text = 'Crystal symmetry';
            app.CrystalsymmetryDropDown = uidropdown(app.PhasePanel_2);
            app.CrystalsymmetryDropDown.Items = {'Select', '0 = Triclinic (C1 - 1) (360, 180, 360)', '1 = Monoclinic (C2 - 2) (360, 180, 180)', '2 = Orthorhombic (D2 - 222) (180, 180, 180)', '3 = Trigonal (D3 - 32) (2-fold//Y) (180, 180, 120)', '4 = Trigonal (C3 - 3) (360, 180, 120)', '5 = Tetragonal (D4 - 422) (180, 180, 90)', '6 = Tetragonal (C4 - 4) (360, 180, 90)', '7 = Hexagonal (D6 - 622) (180, 180, 60)', '8 = Hexagonal (C6 - 6) (360, 180, 60)', '9 = Cubic (0 - 432) (180, 180, 90)', '10 = Cubic (T - 23) (360, 180, 90)'};
            app.CrystalsymmetryDropDown.Value = 'Select';
            app.FoliationwithpolegivenbyAZ0360andINC090Label = uilabel(app.PhasePanel_2);
            app.FoliationwithpolegivenbyAZ0360andINC090Label.Text = '8. Foliation with pole given by AZ (0-360) and INC (0-90)';
            app.AZLabel = uilabel(app.PhasePanel_2);
            app.AZLabel.Text = 'AZ';
            app.INCLabel = uilabel(app.PhasePanel_2);
            app.INCLabel.Text = 'INC';
            app.A1EditFieldLabel = uilabel(app.PhasePanel_2);
            app.A1EditFieldLabel.Text = 'A1';
            app.A1AZEditField = uieditfield(app.PhasePanel_2, 'text');
            app.A1INCEditField = uieditfield(app.PhasePanel_2, 'text');
            app.A3INCEditField = uieditfield(app.PhasePanel_2, 'text');
            app.A3EditFieldLabel = uilabel(app.PhasePanel_2);
            app.A3EditFieldLabel.Text = 'A3';
            app.A3AZEditField = uieditfield(app.PhasePanel_2, 'text');
            app.SaveGrainShapeOrientationButton = uibutton(app.PhasePanel_2, 'push');
            app.SaveGrainShapeOrientationButton.ButtonPushedFcn = createCallbackFcn(app, @SaveGrainShapeOrientationButtonPushed, true);
            app.SaveGrainShapeOrientationButton.Text = 'Save';
            app.ClearGrainShapeOrientationButton = uibutton(app.PhasePanel_2, 'push');
            app.ClearGrainShapeOrientationButton.ButtonPushedFcn = createCallbackFcn(app, @ClearGrainShapeOrientationButtonPushed, true);
            app.ClearGrainShapeOrientationButton.Text = 'Clear phase';
            app.ProperpointgroupsSchoenfliesInternationalphi1PHIphi2Label = uilabel(app.PhasePanel_2);
            app.ProperpointgroupsSchoenfliesInternationalphi1PHIphi2Label.Text = '11 Proper point groups (Schoenflies - International) (phi1, PHI, phi2)';
            app.X1ind1EditField = uieditfield(app.PhasePanel_2, 'text');
            app.X1ind2EditField = uieditfield(app.PhasePanel_2, 'text');
            app.X1ind3EditField = uieditfield(app.PhasePanel_2, 'text');
            app.X3ind1EditField = uieditfield(app.PhasePanel_2, 'text');
            app.X3ind2EditField = uieditfield(app.PhasePanel_2, 'text');
            app.X3ind3EditField = uieditfield(app.PhasePanel_2, 'text');
            app.EllipsoidsemiaxeslengthsA1A2A3Label = uilabel(app.PhasePanel_2);
            app.EllipsoidsemiaxeslengthsA1A2A3Label.Text = '7. Ellipsoid semi-axes lengths (A1, A2, A3) ';
            app.EllipsoidA1EditField = uieditfield(app.PhasePanel_2, 'text');
            app.EllipsoidA2EditField = uieditfield(app.PhasePanel_2, 'text');
            app.EllipsoidA3EditField = uieditfield(app.PhasePanel_2, 'text');
            app.Note0NocrystalsymmetryoperationsegfluidsLabel = uilabel(app.PhasePanel_2);
            app.Note0NocrystalsymmetryoperationsegfluidsLabel.Text = '*Note: 0 = No crystal symmetry operations (e.g., fluids)';
            app.LoadGrainShapeOrientationButton = uibutton(app.PhasePanel_2, 'push');
            app.LoadGrainShapeOrientationButton.ButtonPushedFcn = createCallbackFcn(app, @LoadGrainShapeOrientationButtonPushed, true);
            app.LoadGrainShapeOrientationButton.Text = 'Load';
            app.NameEditField_3Label = uilabel(app.PhasePanel_2);
            app.NameEditField_3Label.Text = 'Name:';
            app.LoadGrainShapeOrientationNameEditField = uieditfield(app.PhasePanel_2, 'text');
            app.SummaryTab = uitab(app.TabGroup);
            app.SummaryTab.Title = 'Summary';
            app.SummaryTextArea = uitextarea(app.SummaryTab);
            app.SummaryTextArea.Editable = 'off';
            app.ShowButton = uibutton(app.SummaryTab, 'push');
            app.ShowButton.ButtonPushedFcn = createCallbackFcn(app, @ShowButtonPushed, true);
            app.ShowButton.Text = 'Show';
            app.HelpTab = uitab(app.TabGroup);
            app.HelpTab.Title = 'Help';
            app.TabGroup2 = uitabgroup(app.HelpTab);
            app.AboutAnisEulerSCInputsTab = uitab(app.TabGroup2);
            app.AboutAnisEulerSCInputsTab.Title = 'About AnisEulerSC-Inputs';
            app.AnisEulerSCInputsisdesignedtoLabel = uilabel(app.AboutAnisEulerSCInputsTab);
            app.AnisEulerSCInputsisdesignedtoLabel.Text = {'AnisEulerSC-Inputs is ...'; ''; '     - Designed for generating the input data of the AnisEulerSC program that is developed for self-consistent approximation. '; ''; '     - Composed of five tabs designed as graphical user interfaces (GUIs).'; ''; ''; 'AnisEulerSC-Inputs requires ...'; ''; '     - Composition of a polycrystalline aggregate.'; ''; '     - Volume fractions of constituent phases.'; ''; '     - Grain shapes and orientations of each phase.'; ''; ''; 'AnisEulerSC-Inputs is designed using the App Designer of MATLAB (R2019a) in Mac OS X. '; ''; ''; 'AnisEulerSC-Inputs runs in the MATLAB version after R2016b on Windows or UNIX platforms.'; ''; ''; '*Note 1: File separator character is a backslash (\) on Windows platforms, whereas it is forward slash (/) on UNIX platforms.'; ''; '     - C:\Users\ekim\Documents\MATLAB\AnisEulerSC\ (Windows)'; ''; '     - /Users/ekim/Documents/MATLAB/AnisEulerSC/ (UNIX)'; ''; '*Note 2: Input data file (''Title_Inputs_ModelSC.mat) should be loaded in the script of AnisEulerSC program (see User Guide for details).'; ''; ''; ''; ''; 'LAST REVISED: January 13, 2020'; 'EUNYOUNG KIM'; 'Seoul National University, Seoul, Republic of Korea'; 'brilliant@snu.ac.kr'; ' '; ''};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AnisEulerSC_Inputs

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end